classdef quadcopter_delivery_tradeoff_cost_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeliveryTradeoffCost          matlab.ui.Figure
        EditDeliveryRadiusValue       matlab.ui.control.NumericEditField
        DeliveryPricePerKG            matlab.ui.control.NumericEditField
        PerKgEditField_2Label         matlab.ui.control.Label
        DeliveryPriceMinimum          matlab.ui.control.NumericEditField
        MinimumEditFieldLabel_2       matlab.ui.control.Label
        DeliveryCostFixed             matlab.ui.control.NumericEditField
        FixedEditField_2Label         matlab.ui.control.Label
        DeliveryCostVariable          matlab.ui.control.NumericEditField
        VariablekgkmEditField_2Label  matlab.ui.control.Label
        PricePerDeliveryLabel         matlab.ui.control.Label
        CostsPerDeliveryLabel         matlab.ui.control.Label
        MaxLoadEditField              matlab.ui.control.NumericEditField
        MaxMassLabel                  matlab.ui.control.Label
        QuadcopterDeliveryCostTradeoffAppLabel  matlab.ui.control.Label
        DistributionofDeliveryMassesSlider  matlab.ui.control.Slider
        DistributionofDeliveryMassesSliderLabel  matlab.ui.control.Label
        DeliveryRadiuskmSlider        matlab.ui.control.Slider
        DeliveryRadiuskmLabel         matlab.ui.control.Label
        MissionCosts                  matlab.ui.control.UIAxes
        LoadProbabilityDensity        matlab.ui.control.UIAxes
        MissionRangeHistogram         matlab.ui.control.UIAxes
        DeliveryZoneAxes              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        DeliveryZoneCircle   % Handle for circle
        DeliveryZonePatch    % Handle for patch
        DeliveryZoneCustomers% Handle for Delivery Zone legend
        DeliveryDistanceHist % Handle for distance histogram
        DeliveryCustomersX   % X-locations for customers
        DeliveryCustomersY   % Y-locations for customers
        DeliveryCustomersRad % Radius for customers
        LoadBetaCoeffA       % Load Mass Distribution Beta Coeff A
        LoadBetaCoeffB       % Load Mass Distribution Beta Coeff B
        radiusValidInds      % Indices of valid delivery locations
        data_diameter = 4;
        MaxLoadMass          % Max load mass in kg
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %% Set Up Delivery Zone Axes
            [img, ~, ImageAlpha] = imread('Dublin_DroneArea_Image.png');
            %data_diameter = 4;
            image(app.DeliveryZoneAxes,[-1 1]*app.data_diameter/2,[-1 1]*app.data_diameter/2,...
                img,'AlphaData',ImageAlpha);
            axis(app.DeliveryZoneAxes,'equal');
            img_Gr = img(:,:,2);

            % Identify corners
            corners = detectHarrisFeatures(img_Gr,'FilterSize',5);

            px = corners.Location(:,1);
            d_px = max(px)-min(px);
            px_sc = (px-d_px/2)/d_px*app.data_diameter;

            py = corners.Location(:,2);
            d_py = max(py)-min(py);
            py_sc = (py-d_py/2)/d_py*app.data_diameter;

            % Calculate distance from center to points
            rad_set = (px_sc.^2+py_sc.^2).^0.5;

            % Eliminate points on outer edge
            radEdgeInds = find(rad_set<1.98);
            rad_setNew = rad_set(radEdgeInds,:);
            rad_set = [];
            rad_set = rad_setNew;

            app.DeliveryCustomersX = px_sc(radEdgeInds);
            app.DeliveryCustomersY = py_sc(radEdgeInds);
            app.DeliveryCustomersRad = rad_set;

            % Plot points
            hold(app.DeliveryZoneAxes,'on')
            app.DeliveryZoneCustomers = plot(app.DeliveryZoneAxes,px_sc(radEdgeInds),py_sc(radEdgeInds),'m.');
            %,'Color','#D78825');
            hold(app.DeliveryZoneAxes,'off')

            % Plot circle
            radius_init = app.DeliveryRadiuskmSlider.Value;
            angPts = linspace(0,2*pi,100);
            hold(app.DeliveryZoneAxes,'on')
            app.DeliveryZoneCircle = plot(app.DeliveryZoneAxes,sin(angPts)*radius_init,cos(angPts)*radius_init,'c','LineWidth',1,'DisplayName','','HandleVisibility','off');
            set(app.DeliveryZoneCircle,'Tag','Radius');
            hold(app.DeliveryZoneAxes,'off')

            % Plot patch
            xptch  = [app.data_diameter/2*sin(angPts) radius_init*sin(fliplr(angPts))];
            yptch  = [app.data_diameter/2*cos(angPts) radius_init*cos(fliplr(angPts))];
            hold(app.DeliveryZoneAxes,'on')
            app.DeliveryZonePatch = patch(app.DeliveryZoneAxes,xptch,yptch,[1 1 1],'FaceAlpha',0.6,'EdgeColor','none','DisplayName','','HandleVisibility','off');
            hold(app.DeliveryZoneAxes,'off')
            set(app.DeliveryZonePatch,'Tag','Patch');
            legend(app.DeliveryZoneAxes,'Location','NorthWest')

            %% Set Up Delivery Distance Histogram
            app.radiusValidInds = find(rad_set<radius_init);
            rad4Histogram = app.DeliveryCustomersRad(app.radiusValidInds);
            app.DeliveryDistanceHist = histogram(app.MissionRangeHistogram,'Data',rad4Histogram,'NumBins',50);

            % Adjust legend
            app.DeliveryZoneCustomers.DisplayName = num2str(length(app.radiusValidInds));

            %% Set Up Load Probability Density
            app.MaxLoadMass = app.MaxLoadEditField.Value;
            app.DistributionofDeliveryMassesSliderValueChanged;

            %% Set up cost index
            app.MissionCostsButtonDown;
        end

        % Value changed function: DeliveryRadiuskmSlider
        function DeliveryRadiuskmSliderValueChanged(app, event)

            radius_zone = app.DeliveryRadiuskmSlider.Value;
            app.EditDeliveryRadiusValue.Value = radius_zone;

            angPts = linspace(0,2*pi,100);
            app.DeliveryZoneCircle.XData = sin(angPts)*radius_zone;
            app.DeliveryZoneCircle.YData = cos(angPts)*radius_zone;

            xptch  = [app.data_diameter/2*sin(angPts) radius_zone*sin(fliplr(angPts))];
            yptch  = [app.data_diameter/2*cos(angPts) radius_zone*cos(fliplr(angPts))];
            app.DeliveryZonePatch.Vertices = [xptch' yptch'];

            app.radiusValidInds = find(app.DeliveryCustomersRad<radius_zone);
            app.DeliveryZoneCustomers.DisplayName = num2str(length(app.radiusValidInds));
            rad4Histogram = app.DeliveryCustomersRad(app.radiusValidInds);
            numBins = ceil(max(rad4Histogram)*30);
            app.DeliveryDistanceHist = histogram(app.MissionRangeHistogram,'Data',rad4Histogram,'NumBins',numBins);
            app.MissionCostsButtonDown;
        end

        % Value changed function: DistributionofDeliveryMassesSlider
        function DistributionofDeliveryMassesSliderValueChanged(app, event)
            value  = app.DistributionofDeliveryMassesSlider.Value;

            aVec   = [1.5 2 2.5 5 5 5 5];
            bVec   = fliplr(aVec);
            inVec  = linspace(0,1,length(aVec));
            app.LoadBetaCoeffA = interp1(inVec,aVec,value);
            app.LoadBetaCoeffB = interp1(inVec,bVec,value);
            %coeffA = app.DistributionofDeliveryMassesSlider.Value;
            %coeffB = app.CoeffBSlider.Value;
            max_weight = app.MaxLoadMass;  % kg
            x = [0:0.01:1];
            Y = betapdf(x,app.LoadBetaCoeffA,app.LoadBetaCoeffB)/max_weight;
            plot(app.LoadProbabilityDensity,x*max_weight,Y);
            %text(app.LoadProbabilityDensity,0.5,0.7,...
            %    ['CoeffA: ' sprintf('%2.2f\n',app.LoadBetaCoeffA) 'CoeffB: ' sprintf('%2.2f',app.LoadBetaCoeffB) ])

            app.MissionCostsButtonDown;
        end

        % Callback function
        function CoeffBSliderValueChanged(app, event)
            value = app.CoeffBSlider.Value;
            app.DistributionofDeliveryMassesSliderValueChanged;
        end

        % Button down function: MissionCosts
        function MissionCostsButtonDown(app, event)

            % Obtain parameters fom the app (Properties)
            coeffA = app.LoadBetaCoeffA;
            coeffB = app.LoadBetaCoeffB;
            radiusCustomers = app.DeliveryCustomersRad;
            radiusCustValidInds = app.radiusValidInds;
            max_weight = app.MaxLoadMass;
            delFixedCost  = app.DeliveryCostFixed.Value;
            delVarCost = app.DeliveryCostVariable.Value;
            delPricePerKg   = app.DeliveryPricePerKG.Value;
            delPriceMin     = app.DeliveryPriceMinimum.Value;

            %delFixedCost  = app.DeliveryFixedCost;
            %delVarCost    = app.DeliveryVariableCost;
            %delPricePerKg = app.DeliveryPricePerKG;
            %delPriceMin   = app.DeliveryPriceMin;

            % Generate random set of loads according to selected load distribution
            rad4Histogram = radiusCustomers(radiusCustValidInds);
            rng('default'); % For repeatability set random seed to default
            load_set = betarnd(coeffA,coeffB,length(rad4Histogram),1)*max_weight;

            % Calculate cost per mission
            min_price = 0.6;
            %missionCostIndex = max(min_price,load_set.*rad4Histogram);
            %missionCostIndex = (load_set.*rad4Histogram+0.3)*2;

            %delFixedCost     = 0.6;  % Euros
            %delVarCost  = 2;    % Euros/(km*kg)
            %missionCostIndex  = (load_set.*rad_set(validInds)+0.3)*2;
            missionCostIndex     = ...
                delVarCost*load_set.*rad4Histogram + ...
                delFixedCost;

            % Produce histogram of costs
            warning('off','MATLAB:nearlySingularMatrix')
            h1=histfit(app.MissionCosts,missionCostIndex,50,'gamma');
            warning('on','MATLAB:nearlySingularMatrix')
            
            % Calculate revenue
            %delPriceMin     = 2.8; % Euros
            %delPricePerKg   = 2.8; % Euros/kg

            Revenue        = sum(max(load_set*delPricePerKg, delPriceMin));

            %Revenue        = size(missionCostIndex,1)*1; ORIG
            %Revenue        = sum(max(0.7, load_set*0.7))*2*2; % NEW
            CostPerMission = mean(missionCostIndex);
            Profit         = Revenue-sum(missionCostIndex);

            % Results
            text(app.MissionCosts,0.5,0.9,...
                ['Revenue: ' sprintf('%2.2f€',Revenue)], ...
                'Units','Normalized',...
                'Color','#004B87', ...
                'Backgroundcolor','w',...
                'VerticalAlignment','Top')
            text(app.MissionCosts,0.5,0.77,...
                [sprintf('%s\n%s  %s','Cost Per','Delivery:', sprintf('%2.2f€',CostPerMission))], ...
                'Units','Normalized',...
                'Color','#FF6400', ...
                'Backgroundcolor','w',...
                'VerticalAlignment','Top')
            text(app.MissionCosts,0.5,0.5,...
                ['Profit: ' sprintf('%2.2f€',Profit)], ...
                'Units','Normalized',...
                'FontSize',14, ...
                'FontWeight','Bold','Color','#228B22', ...
                'Backgroundcolor','w',...
                'VerticalAlignment','Top')

        end

        % Callback function
        function EditFieldDeliveryRadiusValueChanged(app, event)
            value = app.EditFieldDeliveryRadius.Value;
            %disp(value)
            app.DeliveryRadiuskmSlider.Value = max(0,min(str2num(value),2));
            app.DeliveryRadiuskmSliderValueChanged;
        end

        % Value changed function: MaxLoadEditField
        function MaxLoadEditFieldValueChanged(app, event)
            value = app.MaxLoadEditField.Value;
            app.MaxLoadMass = value;
            app.DistributionofDeliveryMassesSliderValueChanged;
        end

        % Value changed function: DeliveryCostFixed
        function DeliveryCostFixedValueChanged(app, event)
            app.MissionCostsButtonDown;
        end

        % Value changed function: DeliveryCostVariable
        function DeliveryCostVariableValueChanged(app, event)
            app.MissionCostsButtonDown;
        end

        % Value changed function: DeliveryPricePerKG
        function DeliveryPricePerKGValueChanged(app, event)
            app.MissionCostsButtonDown;
        end

        % Value changed function: DeliveryPriceMinimum
        function DeliveryPriceMinimumValueChanged(app, event)
            app.MissionCostsButtonDown;
        end

        % Value changed function: EditDeliveryRadiusValue
        function EditDeliveryRadiusValueValueChanged(app, event)
            value = app.EditDeliveryRadiusValue.Value;
            app.DeliveryRadiuskmSlider.Value = value;
            app.DeliveryRadiuskmSliderValueChanged;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeliveryTradeoffCost = uifigure('Visible', 'off');
            app.DeliveryTradeoffCost.Name = 'MATLAB App';
            app.DeliveryZoneAxes = uiaxes(app.DeliveryTradeoffCost);
            title(app.DeliveryZoneAxes, 'Customers in Delivery Zone')
            xlabel(app.DeliveryZoneAxes, 'Distance (km)')
            ylabel(app.DeliveryZoneAxes, 'Distance (km)')
            zlabel(app.DeliveryZoneAxes, 'Z')
            app.DeliveryZoneAxes.XLim = [-2 2];
            app.DeliveryZoneAxes.YLim = [-2 2];
            app.MissionRangeHistogram = uiaxes(app.DeliveryTradeoffCost);
            title(app.MissionRangeHistogram, 'Delivery Distances')
            xlabel(app.MissionRangeHistogram, 'Distance (km)')
            ylabel(app.MissionRangeHistogram, 'Customers')
            zlabel(app.MissionRangeHistogram, 'Z')
            app.MissionRangeHistogram.XLim = [0 2];
            app.LoadProbabilityDensity = uiaxes(app.DeliveryTradeoffCost);
            title(app.LoadProbabilityDensity, 'Statistical Distribution of Delivery Mass')
            xlabel(app.LoadProbabilityDensity, 'Delivery Mass (kg)')
            ylabel(app.LoadProbabilityDensity, 'Probability Density')
            zlabel(app.LoadProbabilityDensity, 'Z')
            app.LoadProbabilityDensity.XLim = [0 2];
            app.LoadProbabilityDensity.YLim = [0 4];
            app.MissionCosts = uiaxes(app.DeliveryTradeoffCost);
            title(app.MissionCosts, 'Operational Cost of Deliveries')
            xlabel(app.MissionCosts, 'Cost/Delivery(€)')
            ylabel(app.MissionCosts, '# of Deliveries')
            zlabel(app.MissionCosts, 'Z')
            app.MissionCosts.ButtonDownFcn = createCallbackFcn(app, @MissionCostsButtonDown, true);
            app.DeliveryRadiuskmLabel = uilabel(app.DeliveryTradeoffCost);
            app.DeliveryRadiuskmLabel.Text = {'Delivery'; 'Radius,'; '(km)'};
            app.DeliveryRadiuskmSlider = uislider(app.DeliveryTradeoffCost);
            app.DeliveryRadiuskmSlider.Limits = [0.1 2];
            app.DeliveryRadiuskmSlider.MajorTicks = [0.1 0.5 1 1.5 2];
            app.DeliveryRadiuskmSlider.ValueChangedFcn = createCallbackFcn(app, @DeliveryRadiuskmSliderValueChanged, true);
            app.DeliveryRadiuskmSlider.Value = 1;
            app.DistributionofDeliveryMassesSliderLabel = uilabel(app.DeliveryTradeoffCost);
            app.DistributionofDeliveryMassesSliderLabel.Text = {'Distribution'; 'of Delivery'; 'Masses'};
            app.DistributionofDeliveryMassesSlider = uislider(app.DeliveryTradeoffCost);
            app.DistributionofDeliveryMassesSlider.Limits = [0 1];
            app.DistributionofDeliveryMassesSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.DistributionofDeliveryMassesSlider.MajorTickLabels = {'Light', ' ', ' ', ' ', 'Heavy'};
            app.DistributionofDeliveryMassesSlider.ValueChangedFcn = createCallbackFcn(app, @DistributionofDeliveryMassesSliderValueChanged, true);
            app.DistributionofDeliveryMassesSlider.Value = 1;
            app.QuadcopterDeliveryCostTradeoffAppLabel = uilabel(app.DeliveryTradeoffCost);
            app.QuadcopterDeliveryCostTradeoffAppLabel.Text = 'Quadcopter Delivery Cost Tradeoff App';
            app.MaxMassLabel = uilabel(app.DeliveryTradeoffCost);
            app.MaxMassLabel.Text = {'Max'; 'Load'};
            app.MaxLoadEditField = uieditfield(app.DeliveryTradeoffCost, 'numeric');
            app.MaxLoadEditField.Limits = [0.1 2];
            app.MaxLoadEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLoadEditFieldValueChanged, true);
            app.MaxLoadEditField.Value = 2;
            app.CostsPerDeliveryLabel = uilabel(app.DeliveryTradeoffCost);
            app.CostsPerDeliveryLabel.Text = 'Costs Per Delivery';
            app.PricePerDeliveryLabel = uilabel(app.DeliveryTradeoffCost);
            app.PricePerDeliveryLabel.Text = 'Price Per Delivery';
            app.VariablekgkmEditField_2Label = uilabel(app.DeliveryTradeoffCost);
            app.VariablekgkmEditField_2Label.Text = 'Variable(€/(kg*km))';
            app.DeliveryCostVariable = uieditfield(app.DeliveryTradeoffCost, 'numeric');
            app.DeliveryCostVariable.Limits = [0 Inf];
            app.DeliveryCostVariable.ValueDisplayFormat = '%2.2f';
            app.DeliveryCostVariable.ValueChangedFcn = createCallbackFcn(app, @DeliveryCostVariableValueChanged, true);
            app.DeliveryCostVariable.Editable = 'off';
            app.DeliveryCostVariable.Value = 2;
            app.FixedEditField_2Label = uilabel(app.DeliveryTradeoffCost);
            app.FixedEditField_2Label.Text = 'Fixed(€)             ';
            app.DeliveryCostFixed = uieditfield(app.DeliveryTradeoffCost, 'numeric');
            app.DeliveryCostFixed.Limits = [0 Inf];
            app.DeliveryCostFixed.ValueDisplayFormat = '%2.2f';
            app.DeliveryCostFixed.ValueChangedFcn = createCallbackFcn(app, @DeliveryCostFixedValueChanged, true);
            app.DeliveryCostFixed.Editable = 'off';
            app.DeliveryCostFixed.Value = 0.6;
            app.MinimumEditFieldLabel_2 = uilabel(app.DeliveryTradeoffCost);
            app.MinimumEditFieldLabel_2.Text = 'Minimum(€)';
            app.DeliveryPriceMinimum = uieditfield(app.DeliveryTradeoffCost, 'numeric');
            app.DeliveryPriceMinimum.Limits = [0 Inf];
            app.DeliveryPriceMinimum.ValueDisplayFormat = '%2.2f';
            app.DeliveryPriceMinimum.ValueChangedFcn = createCallbackFcn(app, @DeliveryPriceMinimumValueChanged, true);
            app.DeliveryPriceMinimum.Editable = 'off';
            app.DeliveryPriceMinimum.Value = 3;
            app.PerKgEditField_2Label = uilabel(app.DeliveryTradeoffCost);
            app.PerKgEditField_2Label.Text = 'Per Kg(€)';
            app.DeliveryPricePerKG = uieditfield(app.DeliveryTradeoffCost, 'numeric');
            app.DeliveryPricePerKG.Limits = [0 Inf];
            app.DeliveryPricePerKG.ValueDisplayFormat = '%2.2f';
            app.DeliveryPricePerKG.ValueChangedFcn = createCallbackFcn(app, @DeliveryPricePerKGValueChanged, true);
            app.DeliveryPricePerKG.Editable = 'off';
            app.DeliveryPricePerKG.Value = 2.9;
            app.EditDeliveryRadiusValue = uieditfield(app.DeliveryTradeoffCost, 'numeric');
            app.EditDeliveryRadiusValue.Limits = [0.1 Inf];
            app.EditDeliveryRadiusValue.ValueDisplayFormat = '%2.2f';
            app.EditDeliveryRadiusValue.ValueChangedFcn = createCallbackFcn(app, @EditDeliveryRadiusValueValueChanged, true);
            app.EditDeliveryRadiusValue.Value = 1;
            % Show the figure after all components are created
            app.DeliveryTradeoffCost.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quadcopter_delivery_tradeoff_cost_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeliveryTradeoffCost)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeliveryTradeoffCost)
        end
    end
end