classdef quadcopter_drone_exercises_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Quadcopter_Drone_Exercises     matlab.ui.Figure
        StatusLabel                    matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ClickabuttonbelowtostartLabel  matlab.ui.control.Label
        AutomatingDroneAnalysisUsingSimulationLabel  matlab.ui.control.Label
        Exercise5ProfitabilityTradeoffButton  matlab.ui.control.Button
        Exercise4TestStabilityandRangeButton  matlab.ui.control.Button
        Exercise3ObtainLocalWeatherButton  matlab.ui.control.Button
        Exercise2SizeMotorButton       matlab.ui.control.Button
        Exercise1Integrate3DPropellerButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Exercise1Integrate3DPropellerButton
        function Exercise1Integrate3DPropellerButtonPushed(app, event)
            app.StatusLabel.Text = 'Opening Exercise 1...';
            pause(0.1);
            quadcopter_drone_exercises(1)
            app.StatusLabel.Text = 'Opening Exercise 1... done.';
        end

        % Button pushed function: Exercise2SizeMotorButton
        function Exercise2SizeMotorButtonPushed(app, event)
            app.StatusLabel.Text = 'Opening Exercise 2...';
            pause(0.1);
            quadcopter_drone_exercises(2)
            app.StatusLabel.Text = 'Opening Exercise 2... done.';
        end

        % Button pushed function: Exercise3ObtainLocalWeatherButton
        function Exercise3ObtainLocalWeatherButtonPushed(app, event)
            app.StatusLabel.Text = 'Opening Exercise 3...';
            pause(0.1);
            quadcopter_drone_exercises(3)
            app.StatusLabel.Text = 'Opening Exercise 3... done.';
        end

        % Button pushed function: Exercise4TestStabilityandRangeButton
        function Exercise4TestStabilityandRangeButtonPushed(app, event)
            app.StatusLabel.Text = 'Opening Exercise 4...';
            pause(0.1);
            quadcopter_drone_exercises(4)
            app.StatusLabel.Text = 'Opening Exercise 4... done.';
        end

        % Button pushed function: Exercise5ProfitabilityTradeoffButton
        function Exercise5ProfitabilityTradeoffButtonPushed(app, event)
            app.StatusLabel.Text = 'Opening Exercise 5...';
            pause(0.1);
            quadcopter_drone_exercises(5)
            app.StatusLabel.Text = 'Opening Exercise 5... done.';
        end

        % Close request function: Quadcopter_Drone_Exercises
        function Quadcopter_Drone_ExercisesCloseRequest(app, event)
            reopen_link = '<a href = "matlab:quadcopter_drone_exercises_app_run;">here</a>';
            disp('Hope you enjoyed the workshop.')
            disp(['To reopen Workshop Exercise Selection App, click ' reopen_link]);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Quadcopter_Drone_Exercises = uifigure('Visible', 'off');
            app.Quadcopter_Drone_Exercises.Name = 'Exercise Selection App';
            app.Quadcopter_Drone_Exercises.CloseRequestFcn = createCallbackFcn(app, @Quadcopter_Drone_ExercisesCloseRequest, true);
            app.Exercise1Integrate3DPropellerButton = uibutton(app.Quadcopter_Drone_Exercises, 'push');
            app.Exercise1Integrate3DPropellerButton.ButtonPushedFcn = createCallbackFcn(app, @Exercise1Integrate3DPropellerButtonPushed, true);
            app.Exercise1Integrate3DPropellerButton.Text = 'Exercise 1: Integrate 3D Propeller';
            app.Exercise2SizeMotorButton = uibutton(app.Quadcopter_Drone_Exercises, 'push');
            app.Exercise2SizeMotorButton.ButtonPushedFcn = createCallbackFcn(app, @Exercise2SizeMotorButtonPushed, true);
            app.Exercise2SizeMotorButton.Text = 'Exercise 2: Size Motor';
            app.Exercise3ObtainLocalWeatherButton = uibutton(app.Quadcopter_Drone_Exercises, 'push');
            app.Exercise3ObtainLocalWeatherButton.ButtonPushedFcn = createCallbackFcn(app, @Exercise3ObtainLocalWeatherButtonPushed, true);
            app.Exercise3ObtainLocalWeatherButton.Text = 'Exercise 3: Obtain Local Weather';
            app.Exercise4TestStabilityandRangeButton = uibutton(app.Quadcopter_Drone_Exercises, 'push');
            app.Exercise4TestStabilityandRangeButton.ButtonPushedFcn = createCallbackFcn(app, @Exercise4TestStabilityandRangeButtonPushed, true);
            app.Exercise4TestStabilityandRangeButton.Text = 'Exercise 4: Test Stability and Range';
            app.Exercise5ProfitabilityTradeoffButton = uibutton(app.Quadcopter_Drone_Exercises, 'push');
            app.Exercise5ProfitabilityTradeoffButton.ButtonPushedFcn = createCallbackFcn(app, @Exercise5ProfitabilityTradeoffButtonPushed, true);
            app.Exercise5ProfitabilityTradeoffButton.Text = 'Exercise 5: Profitability Tradeoff';
            app.AutomatingDroneAnalysisUsingSimulationLabel = uilabel(app.Quadcopter_Drone_Exercises);
            app.AutomatingDroneAnalysisUsingSimulationLabel.Text = 'Automating Drone Analysis Using Simulation';
            app.ClickabuttonbelowtostartLabel = uilabel(app.Quadcopter_Drone_Exercises);
            app.ClickabuttonbelowtostartLabel.Text = {'Click any button below to start an exercise. '; '(other models and MATLAB figure windows will be closed).'; 'They can be performed in any order.'};
            app.Image = uiimage(app.Quadcopter_Drone_Exercises);
            app.Image.ImageSource = 'quadcopter_drone_for_app_droneLoad.png';
            app.StatusLabel = uilabel(app.Quadcopter_Drone_Exercises);
            app.StatusLabel.Text = '';
            % Show the figure after all components are created
            app.Quadcopter_Drone_Exercises.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quadcopter_drone_exercises_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Quadcopter_Drone_Exercises)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Quadcopter_Drone_Exercises)
        end
    end
end